classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReaoemCadeiaUIFigure        matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ReaoemCadeiaTab             matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        Gauge                       matlab.ui.control.Gauge
        SimulandoLamp               matlab.ui.control.Lamp
        SimulandoLabel              matlab.ui.control.Label
        NutronsSpinner              matlab.ui.control.Spinner
        NutronsLabel                matlab.ui.control.Label
        Urnio238Spinner             matlab.ui.control.Spinner
        Urnio238SpinnerLabel        matlab.ui.control.Label
        Urnio235Spinner             matlab.ui.control.Spinner
        Urnio235SpinnerLabel        matlab.ui.control.Label
        TempoLabel                  matlab.ui.control.Label
        STOPButton                  matlab.ui.control.Button
        STARTButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ConfigTab                   matlab.ui.container.Tab
        SimulaoPanel                matlab.ui.container.Panel
        tEditField                  matlab.ui.control.NumericEditField
        tLabel                      matlab.ui.control.Label
        tfEditField                 matlab.ui.control.NumericEditField
        tfEditFieldLabel            matlab.ui.control.Label
        t0EditField                 matlab.ui.control.NumericEditField
        t0EditFieldLabel            matlab.ui.control.Label
        GridPanel                   matlab.ui.container.Panel
        gridYEditField              matlab.ui.control.NumericEditField
        gridYEditFieldLabel         matlab.ui.control.Label
        gridXEditField              matlab.ui.control.NumericEditField
        gridXEditFieldLabel         matlab.ui.control.Label
        VelocidadesPanel            matlab.ui.container.Panel
        CriptnioV0yEditField        matlab.ui.control.NumericEditField
        CriptnioV0yEditFieldLabel   matlab.ui.control.Label
        CriptnioV0xEditField        matlab.ui.control.NumericEditField
        CriptnioV0xEditFieldLabel   matlab.ui.control.Label
        BrioV0yEditField            matlab.ui.control.NumericEditField
        BrioV0yEditFieldLabel       matlab.ui.control.Label
        BrioV0xEditField            matlab.ui.control.NumericEditField
        BrioV0xEditFieldLabel       matlab.ui.control.Label
        NutronV0yEditField          matlab.ui.control.NumericEditField
        NutronV0yEditFieldLabel     matlab.ui.control.Label
        NutronV0xEditField          matlab.ui.control.NumericEditField
        NutronV0xEditFieldLabel     matlab.ui.control.Label
        RaiosPanel                  matlab.ui.container.Panel
        raioU238EditField           matlab.ui.control.NumericEditField
        raioU238EditFieldLabel      matlab.ui.control.Label
        raioU235EditField           matlab.ui.control.NumericEditField
        raioU235EditFieldLabel      matlab.ui.control.Label
        raioCriptnioEditField       matlab.ui.control.NumericEditField
        raioCriptnioEditFieldLabel  matlab.ui.control.Label
        raioBrioEditField           matlab.ui.control.NumericEditField
        raioBrioEditFieldLabel      matlab.ui.control.Label
        raioNutronEditField         matlab.ui.control.NumericEditField
        raioNutronEditFieldLabel    matlab.ui.control.Label
        GrficosTab                  matlab.ui.container.Tab
        MostrarGrficoDropDownLabel  matlab.ui.control.Label
        MostrarGrficoDropDown       matlab.ui.control.DropDown
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        chain; 
        running;
        chainPlot;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.running = true;
            app.SimulandoLamp.Color = [1,1,0];
            pause(0.01);
            
            N235 = app.Urnio235Spinner.Value;
            N238 = app.Urnio238Spinner.Value;
            Nn = app.NutronsSpinner.Value; 
            
            gridSize = [app.gridXEditField.Value,app.gridYEditField.Value];
            
            r235 = app.raioU235EditField.Value;
            r238 = app.raioU238EditField.Value;
            r239 = 15;
            rn = app.raioNutronEditField.Value;
            rBa = app.raioBrioEditField.Value;
            rKr = app.raioCriptnioEditField.Value;
            
            v0_n = [app.NutronV0xEditField.Value,app.NutronV0yEditField.Value];
            v0_Kr = [app.CriptnioV0xEditField.Value,app.CriptnioV0yEditField.Value];
            v0_Ba = [app.BrioV0xEditField.Value,app.BrioV0yEditField.Value];
            
            t0 = app.t0EditField.Value;
            tf = app.tfEditField.Value;
            dt = app.tEditField.Value;
            app.Gauge.Limits = [t0,tf];
            
            mainAX = app.UIAxes;
            cla(mainAX);
            hold(mainAX,'on');
            app.SimulandoLamp.Color = [1,1,0];
            app.chain = ChainReaction(mainAX);
            app.chain = app.chain.GetParameters(gridSize, N235, N238, Nn, r235, r238, r239, rn, rBa, rKr, v0_n, v0_Kr, v0_Ba);
            app.chain = app.chain.GenerateElements();
            
            app.SimulandoLamp.Color = [0,1,0];
            [app.chain,n_real,u_real,m_real,t_real] = app.chain.ChainSim(t0,tf,dt,app1);
            app.SimulandoLamp.Color = [1,0,0];
           
            %% MODEL
            %run model:
            [n_model,u_model,m_model,t_model] = RunModel(N235,Nn,N238);
            %link model to data:
            [n_model,u_model,m_model,t_model] = LinkModelToReal(v0_n,n_real,u_real,m_real,t_real,n_model,u_model,m_model,t_model);
            
            app.chainPlot = ChainPlotClass(app.UIAxes2,app.UIAxes3);
            app.chainPlot = app.chainPlot.getData_real(n_real,u_real,m_real,t_real);   
            app.chainPlot = app.chainPlot.getData_model(n_model,u_model,m_model,t_model);
            app.chainPlot.plotData(app.MostrarGrficoDropDown.Value);
            
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
%             com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C');
%             com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C');
%             com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C');
%             com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C');

            app.chain.running = false;
            app.running = false;
        end

        % Callback function
        function ButtonPushed(app, event)
            app.chain = NewNeutronStart(app.chain);
        end

        % Value changed function: MostrarGrficoDropDown
        function MostrarGrficoDropDownValueChanged(app, event)
            app.chainPlot.plotData(app.MostrarGrficoDropDown.Value)
        end

        % Value changed function: t0EditField
        function t0EditFieldValueChanged(app, event)
            app.Gauge.Limits = [app.t0EditField.Value,app.tfEditField.Value];
        end

        % Value changed function: tfEditField
        function tfEditFieldValueChanged(app, event)
            app.Gauge.Limits = [app.t0EditField.Value,app.tfEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReaoemCadeiaUIFigure = uifigure('Visible', 'off');
            app.ReaoemCadeiaUIFigure.Name = 'Reação em Cadeia';
            app.ReaoemCadeiaUIFigure.Icon = 'icon.png';
            app.TabGroup = uitabgroup(app.ReaoemCadeiaUIFigure);
            app.ReaoemCadeiaTab = uitab(app.TabGroup);
            app.ReaoemCadeiaTab.Title = 'Reação em Cadeia';
            app.GridLayout = uigridlayout(app.ReaoemCadeiaTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '  ')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            zlabel(app.UIAxes, ' ')
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = {'STOP'; ''};
            app.TempoLabel = uilabel(app.GridLayout);
            app.TempoLabel.Text = 'Tempo:';
            app.Urnio235SpinnerLabel = uilabel(app.GridLayout);
            app.Urnio235SpinnerLabel.Text = 'Urânio 235';
            app.Urnio235Spinner = uispinner(app.GridLayout);
            app.Urnio235Spinner.Limits = [0 Inf];
            app.Urnio235Spinner.Value = 70;
            app.Urnio238SpinnerLabel = uilabel(app.GridLayout);
            app.Urnio238SpinnerLabel.Text = {'Urânio 238'; ''};
            app.Urnio238Spinner = uispinner(app.GridLayout);
            app.Urnio238Spinner.Limits = [0 Inf];
            app.Urnio238Spinner.Value = 40;
            app.NutronsLabel = uilabel(app.GridLayout);
            app.NutronsLabel.Text = {'Nêutrons'; ''};
            app.NutronsSpinner = uispinner(app.GridLayout);
            app.NutronsSpinner.Limits = [0 Inf];
            app.NutronsSpinner.Value = 1;
            app.SimulandoLabel = uilabel(app.GridLayout);
            app.SimulandoLabel.Text = 'Simulando:';
            app.SimulandoLamp = uilamp(app.GridLayout);
            app.Gauge = uigauge(app.GridLayout, 'circular');
            app.Gauge.Limits = [0 5];
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.RaiosPanel = uipanel(app.ConfigTab);
            app.RaiosPanel.Title = 'Raios';
            app.raioNutronEditFieldLabel = uilabel(app.RaiosPanel);
            app.raioNutronEditFieldLabel.Text = {'raio Nêutron'; ''};
            app.raioNutronEditField = uieditfield(app.RaiosPanel, 'numeric');
            app.raioNutronEditField.Limits = [1 Inf];
            app.raioNutronEditField.Value = 5;
            app.raioBrioEditFieldLabel = uilabel(app.RaiosPanel);
            app.raioBrioEditFieldLabel.Text = {'raio Bário'; ''};
            app.raioBrioEditField = uieditfield(app.RaiosPanel, 'numeric');
            app.raioBrioEditField.Limits = [1 Inf];
            app.raioBrioEditField.Value = 10;
            app.raioCriptnioEditFieldLabel = uilabel(app.RaiosPanel);
            app.raioCriptnioEditFieldLabel.Text = {'raio Criptônio'; ''};
            app.raioCriptnioEditField = uieditfield(app.RaiosPanel, 'numeric');
            app.raioCriptnioEditField.Limits = [1 Inf];
            app.raioCriptnioEditField.Value = 10;
            app.raioU235EditFieldLabel = uilabel(app.RaiosPanel);
            app.raioU235EditFieldLabel.Text = 'raio U-235';
            app.raioU235EditField = uieditfield(app.RaiosPanel, 'numeric');
            app.raioU235EditField.Limits = [1 Inf];
            app.raioU235EditField.Value = 15;
            app.raioU238EditFieldLabel = uilabel(app.RaiosPanel);
            app.raioU238EditFieldLabel.Text = 'raio U-238';
            app.raioU238EditField = uieditfield(app.RaiosPanel, 'numeric');
            app.raioU238EditField.Limits = [1 Inf];
            app.raioU238EditField.Value = 15;
            app.VelocidadesPanel = uipanel(app.ConfigTab);
            app.VelocidadesPanel.Title = 'Velocidades';
            app.NutronV0xEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.NutronV0xEditFieldLabel.Text = {'Nêutron V0x'; ''};
            app.NutronV0xEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.NutronV0xEditField.Limits = [0 Inf];
            app.NutronV0xEditField.Value = 600;
            app.NutronV0yEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.NutronV0yEditFieldLabel.Text = {'Nêutron V0y'; ''};
            app.NutronV0yEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.NutronV0yEditField.Limits = [0 Inf];
            app.BrioV0xEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.BrioV0xEditFieldLabel.Text = {'Bário V0x'; ''};
            app.BrioV0xEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.BrioV0xEditField.Limits = [0 Inf];
            app.BrioV0xEditField.Value = 150;
            app.BrioV0yEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.BrioV0yEditFieldLabel.Text = {'Bário V0y'; ''};
            app.BrioV0yEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.BrioV0yEditField.Limits = [0 Inf];
            app.BrioV0yEditField.Value = 150;
            app.CriptnioV0xEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.CriptnioV0xEditFieldLabel.Text = 'Criptônio V0x';
            app.CriptnioV0xEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.CriptnioV0xEditField.Limits = [0 Inf];
            app.CriptnioV0xEditField.Value = 150;
            app.CriptnioV0yEditFieldLabel = uilabel(app.VelocidadesPanel);
            app.CriptnioV0yEditFieldLabel.Text = {'Criptônio V0y'; ''};
            app.CriptnioV0yEditField = uieditfield(app.VelocidadesPanel, 'numeric');
            app.CriptnioV0yEditField.Limits = [0 Inf];
            app.CriptnioV0yEditField.Value = 150;
            app.GridPanel = uipanel(app.ConfigTab);
            app.GridPanel.Title = 'Grid';
            app.gridXEditFieldLabel = uilabel(app.GridPanel);
            app.gridXEditFieldLabel.Text = {'gridX'; ''};
            app.gridXEditField = uieditfield(app.GridPanel, 'numeric');
            app.gridXEditField.Limits = [20 Inf];
            app.gridXEditField.Value = 400;
            app.gridYEditFieldLabel = uilabel(app.GridPanel);
            app.gridYEditFieldLabel.Text = {'gridY'; ''};
            app.gridYEditField = uieditfield(app.GridPanel, 'numeric');
            app.gridYEditField.Limits = [20 Inf];
            app.gridYEditField.Value = 400;
            app.SimulaoPanel = uipanel(app.ConfigTab);
            app.SimulaoPanel.Title = 'Simulação';
            app.t0EditFieldLabel = uilabel(app.SimulaoPanel);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.SimulaoPanel, 'numeric');
            app.t0EditField.ValueChangedFcn = createCallbackFcn(app, @t0EditFieldValueChanged, true);
            app.tfEditFieldLabel = uilabel(app.SimulaoPanel);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.SimulaoPanel, 'numeric');
            app.tfEditField.Limits = [0 Inf];
            app.tfEditField.ValueChangedFcn = createCallbackFcn(app, @tfEditFieldValueChanged, true);
            app.tfEditField.Value = 5;
            app.tLabel = uilabel(app.SimulaoPanel);
            app.tLabel.Text = 'Δt';
            app.tEditField = uieditfield(app.SimulaoPanel, 'numeric');
            app.tEditField.Limits = [1e-06 Inf];
            app.tEditField.Value = 0.005;
            app.GrficosTab = uitab(app.TabGroup);
            app.GrficosTab.Title = 'Gráficos';
            app.UIAxes3 = uiaxes(app.GrficosTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GrficosTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MostrarGrficoDropDown = uidropdown(app.GrficosTab);
            app.MostrarGrficoDropDown.Items = {'Urânio 235', 'Nêutrons', 'Urânio 238'};
            app.MostrarGrficoDropDown.ValueChangedFcn = createCallbackFcn(app, @MostrarGrficoDropDownValueChanged, true);
            app.MostrarGrficoDropDown.Value = 'Urânio 235';
            app.MostrarGrficoDropDownLabel = uilabel(app.GrficosTab);
            app.MostrarGrficoDropDownLabel.Text = 'Mostrar Gráfico';
            % Show the figure after all components are created
            app.ReaoemCadeiaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ReaoemCadeiaUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.ReaoemCadeiaUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReaoemCadeiaUIFigure)
        end
    end
end